classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConectScreen             matlab.ui.container.Panel
        ConectGrid               matlab.ui.container.GridLayout
        TomarMedidasBT           matlab.ui.control.Button
        WifiStatusHTML           matlab.ui.control.HTML
        BuscarDispositivosBT     matlab.ui.control.Button
        AvailableDevicesList     matlab.ui.control.ListBox
        EJEMPLOHTML              matlab.ui.control.HTML
        ShowHiddenWifisCheckBox  matlab.ui.control.CheckBox
        GraficasScreen           matlab.ui.container.Panel
        GraficasGrid             matlab.ui.container.GridLayout
        GridLayout               matlab.ui.container.GridLayout
        ConectedLamp             matlab.ui.control.Lamp
        ConectedLBL              matlab.ui.control.Label
        MainScreen               matlab.ui.container.Panel
        MainGrid                 matlab.ui.container.GridLayout
        AccederBT                matlab.ui.control.Button
        Hyperlink                matlab.ui.control.Hyperlink
        SignInStateLBL           matlab.ui.control.Label
        LOGOSEPA                 matlab.ui.control.Image
        SignInLBL                matlab.ui.control.Label
        ChooseActionScreen       matlab.ui.container.Panel
        ChooseActionGrid         matlab.ui.container.GridLayout
        Image                    matlab.ui.control.Image
        VerAnalisisPreviosBt     matlab.ui.control.Button
        ConectarGafasBt          matlab.ui.control.Button
    end


    properties (Access = private)
        WifiOff
        AvailableWifis
        DevicesWifis
        WifiInterface
        WifiTemplate
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        ConnectedDevice = false;
    end

    methods (Access = private)

        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                app.WifiOff = 0;
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ispc
                % ----------------
                % Windows
                % ----------------;
                psCommand = sprintf([
                    '$wifi = Get-NetAdapter -Name "%s"; ' ...
                    'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                    'Start-Sleep -Seconds 2; ' ...
                    'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

                % Ejecutar desde MATLAB
                [status, out] = system(['powershell -Command "', psCommand, '"']);

                % Comprobar errores
                if status ~= 0
                    error("Error forzando reescaneo Wi-Fi:\n%s", out);
                end

                ready = false;

                for k = 1:10

                    pause(1)

                    [~,state] = system('netsh interface show interface');
                    disp(state)
                    disp(app.WifiInterface)
                    lines = splitlines(state);

                    % Buscar la línea de la interfaz WiFi
                    wifiLine = lines(contains(lines, app.WifiInterface));

                    if ~isempty(wifiLine) && contains(wifiLine,["Enabled","Habilitado"])
                        ready = true;
                        break
                    end

                end

                if ~ready
                    error("La interfaz WiFi no se activó correctamente")
                end

                timeout = 10;
                t0 = tic;

                while toc(t0) < timeout

                    [status,out] = system('netsh wlan show networks mode=bssid');

                    if status == 0
                        break
                    end

                    pause(0.5)

                end

                if status ~= 0
                    error("Error escaneando WiFi en Windows:\n%s", out)
                end
                % Cada red tiene línea "SSID 1 : NombreRed"
                lines = splitlines(out);
                ssidLines = lines(startsWith(strtrim(lines),'SSID '));
                for i = 1:length(ssidLines)
                    parts = split(ssidLines{i},':');
                    if numel(parts) >= 2
                        ssid = strtrim(parts{2});
                        if ~isempty(ssid)
                            ssids(end+1) = ssid;
                        end
                    end
                end
            else
                error("Sistema operativo no soportado")
            end

            % Limpiar duplicados y vacíos
            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end

        function ReloadListWifis(app,listWifis)
            app.AvailableDevicesList.Items = {};
            app.AvailableDevicesList.Value = {};
            app.AvailableDevicesList.Items = listWifis;
        end
        function createWifiProfile(app,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            app.WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(app.WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end

        function ChangeWifiStatus(app,state)
            app.WifiStatusHTML.Data = state;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;

            ChangeWindowTo(app,app.MainScreen);

            app.EJEMPLOHTML.HTMLSource = 'buttonTry.html';
            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
        end

        % Button pushed function: AccederBT
        function AccederClicked(app, event)
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        % Button pushed function: ConectarGafasBt
        function ConectarGafasClicked(app, event)
            try
                PrepareWifi(app);
            catch ME
                uialert(app.UIFigure, ME.message, "Error WiFi");
            end
            ChangeWindowTo(app,app.ConectScreen)
            BuscarDispositivosClicked(app);
        end

        % Event received function: EJEMPLOHTML
        function EJEMPLOHTMLClicked(app, event)
            eventName = event.HTMLEventName;
            if strcmp(eventName,'ButtonClicked')
                app.AvailableDevicesList.BackgroundColor = 'white';
            end
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Value changed function: AvailableDevicesList
        function WifiSelected(app, event)
            value = app.AvailableDevicesList.Value;
            ChangeWifiStatus(app,'conectando');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            app.ConnectedDevice = true;
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasBT.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
            end
                
        end

        % Button pushed function: BuscarDispositivosBT
        function BuscarDispositivosClicked(app, event)
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasBT.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            FindAvailableWifis(app);
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ROS", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end

        % Button pushed function: TomarMedidasBT
        function TomarMedidasClicked(app, event)
            ChangeWindowTo(app,app.GraficasScreen);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.ConectarGafasBt = uibutton(app.ChooseActionGrid, 'push');
            app.ConectarGafasBt.ButtonPushedFcn = createCallbackFcn(app, @ConectarGafasClicked, true);
            app.ConectarGafasBt.Text = 'Conectar Gafas Horus';
            app.VerAnalisisPreviosBt = uibutton(app.ChooseActionGrid, 'push');
            app.VerAnalisisPreviosBt.Text = 'Ver Analisis Previos';
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.png');
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'SignInStateLBL';
            app.Hyperlink = uihyperlink(app.MainGrid);
            app.Hyperlink.Text = 'Crear Cuenta';
            app.AccederBT = uibutton(app.MainGrid, 'push');
            app.AccederBT.ButtonPushedFcn = createCallbackFcn(app, @AccederClicked, true);
            app.AccederBT.Text = 'Acceder';
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.GridLayout = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.GridLayout);
            app.ConectedLBL.Text = 'Conected';
            app.ConectedLamp = uilamp(app.GridLayout);
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.EJEMPLOHTML = uihtml(app.ConectGrid);
            app.EJEMPLOHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @EJEMPLOHTMLClicked, true);
            app.AvailableDevicesList = uilistbox(app.ConectGrid);
            app.AvailableDevicesList.Items = {};
            app.AvailableDevicesList.ValueChangedFcn = createCallbackFcn(app, @WifiSelected, true);
            app.AvailableDevicesList.Value = {};
            app.BuscarDispositivosBT = uibutton(app.ConectGrid, 'push');
            app.BuscarDispositivosBT.ButtonPushedFcn = createCallbackFcn(app, @BuscarDispositivosClicked, true);
            app.BuscarDispositivosBT.Icon = fullfile(pathToMLAPP, 'reload.svg');
            app.BuscarDispositivosBT.Text = 'Buscar Dispositivos';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.TomarMedidasBT = uibutton(app.ConectGrid, 'push');
            app.TomarMedidasBT.ButtonPushedFcn = createCallbackFcn(app, @TomarMedidasClicked, true);
            app.TomarMedidasBT.Text = 'Tomar Medidas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end